classdef signalPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RESETButton             matlab.ui.control.Button
        TextArea_2              matlab.ui.control.TextArea
        EquationoftheplotLabel  matlab.ui.control.Label
        SelectasignaltypeLabel  matlab.ui.control.Label
        SelectawaveformLabel    matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        TypeDropDown            matlab.ui.control.DropDown
        TypeDropDownLabel       matlab.ui.control.Label
        WaveformDropDown        matlab.ui.control.DropDown
        WaveformDropDownLabel   matlab.ui.control.Label
        SignalPlotterLabel      matlab.ui.control.Label
        PLOTButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        value1         % type of waveform
        value2         % type of signal (CTS / DTS)
        y              % equation for plotting
        x              % misc
        t1             % misc
        t2             % misc
        sliderVal      % store value of slider for exponential signal
        p              % misc
        equation       % display equation
        x1             % misc
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
            
            value = app.Slider.Value;
            app.sliderVal = value;

        end

        % Value changed function: WaveformDropDown
        function WaveformDropDownValueChanged(app, event)
            
            value = app.WaveformDropDown.Value;
            app.value1 = value;

            app.t1 = 0 : 1;
            app.t2 = -1 : 0.01 : 1;
            app.x = 0 : 0.1: 2 * pi;
            app.x1 = linspace(-5, 5);
            app.p = -2 * pi : 0.1 : 2 * pi;
            

            if app.value1 == "Step"
                app.y = app.t2 >= 0;
            elseif app.value1 == "Ramp"
                app.y = app.x;
            elseif app.value1 == "Parabolic"
                app.y = sqrt(app.x);
            elseif app.value1 == "Sinc"
                app.y = sinc(app.x1);
            elseif app.value1 == "Impulse"
                app.y = app.t1 == 0;
            elseif app.value1 == "Sine"
                app.y = sin(app.x);
            elseif app.value1 == "Exponential"
                app.y = exp(app.t2);
            elseif app.value1 == "Signum"
                app.y = sign((-1 : 0.1 : 1));
            end

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            value = app.TypeDropDown.Value;
            app.value2 = value;

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            app.equation = value;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            if strcmp(app.value2, "CTS")
                if strcmp(app.value1, "Impulse")
                    stem(app.UIAxes, app.y)
                else
                    if strcmp(app.value1, "Sinc")
                        plot(app.UIAxes, app.x1, app.y);
                    elseif strcmp(app.value1, "Signum")
                        plot(app.UIAxes, [-1 : 0.1 : 1], app.y);
                    else
                        plot(app.UIAxes, app.y);
                    end
                end
            elseif strcmp(app.value2, "DTS")
                if strcmp(app.value1, "Sinc")
                    stem(app.UIAxes, app.x1, app.y);
                elseif strcmp(app.value1, "Signum")
                    stem(app.UIAxes, [-1 : 0.1 : 1], app.y);
                else
                    stem(app.UIAxes, app.y);
                end
            end

            if strcmp(app.value2, "CTS")
                if app.value1 == "Step"
                    app.TextArea.Value = sprintf("y = f(t) = [t] for t ∈ R");
                elseif app.value1 == "Ramp"
                    app.TextArea.Value = sprintf("r(t) = kt, t = 0; " + ...
                        "r(t) = 0, t != 0");
                elseif app.value1 == "Parabolic"
                    app.TextArea.Value = sprintf("y^2 = x");
                elseif app.value1 == "Sine"
                    app.TextArea.Value = sprintf("y = sin(wt)");
                elseif app.value1 == "Impulse"
                    app.TextArea.Value = sprintf("δ(t) = 1, t = 0; " + ...
                        "δ(t) = 0, t != 0");
                elseif app.value1 == "Exponential"
                    app.TextArea.Value = sprintf("y = e^t");
                elseif app.value1 == "Signum"
                    app.TextArea.Value = sprintf("x(t) = 1, t >= 0; " + ...
                        "x(t) = -1, t < 0");
                elseif app.value1 == "Sinc"
                    app.TextArea.Value = sprintf("y = sinc = sin(pi * t) / (pi * t)");
                end
            elseif strcmp(app.value2, "DTS")
                if app.value1 == "Step"
                    app.TextArea.Value = sprintf("y = f(n) = [n] for n ∈ R");
                elseif app.value1 == "Ramp"
                    app.TextArea.Value = sprintf("r(n) = kn, n = 0; " + ...
                        "r(n) = 0, n != 0");
                elseif app.value1 == "Parabolic"
                    app.TextArea.Value = sprintf("y^2 = x");
                elseif app.value1 == "Sine"
                    app.TextArea.Value = sprintf("y = sin(wn)");
                elseif app.value1 == "Impulse"
                    app.TextArea.Value = sprintf("δ(n) = 1, n = 0; " + ...
                        "δ(n) = 0, n != 0");
                elseif app.value1 == "Exponential"
                    app.TextArea.Value = sprintf("y = e^n");
                elseif app.value1 == "Signum"
                    app.TextArea.Value = sprintf("x(n) = 1, n >= 0; " + ...
                        "x(n) = -1, n < 0");
                elseif app.value1 == "Sinc"
                    app.TextArea.Value = sprintf("y = sinc = sin(pi * n) / (pi * n)");
                end
    
            end

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cla(app.UIAxes);
            app.TextArea.Value = sprintf("");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude ')
            zlabel(app.UIAxes, 'Z')
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SignalPlotterLabel = uilabel(app.UIFigure);
            app.SignalPlotterLabel.Text = 'Signal Plotter';
            app.WaveformDropDownLabel = uilabel(app.UIFigure);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.UIFigure);
            app.WaveformDropDown.Items = {'Step', 'Ramp', 'Parabolic', 'Sine', 'Impulse', 'Exponential', 'Signum', 'Sinc'};
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformDropDownValueChanged, true);
            app.WaveformDropDown.Value = 'Step';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'CTS', 'DTS'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'CTS';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.SelectawaveformLabel = uilabel(app.UIFigure);
            app.SelectawaveformLabel.Text = 'Select a waveform';
            app.SelectasignaltypeLabel = uilabel(app.UIFigure);
            app.SelectasignaltypeLabel.Text = 'Select a signal type';
            app.EquationoftheplotLabel = uilabel(app.UIFigure);
            app.EquationoftheplotLabel.Text = 'Equation of the plot';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'GROUP 1'; 'Aaryan Shakti - 3001'; 'Abhishek Singh - 3002'; 'Abhishek Yadav - 3003'; 'Aditi Sathe - 3004'; 'Aditya Singh - 3005 '; ''; 'Code can be found on '};
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end